classdef SolModelTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupImportTab                  matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ImportSystemfromFileTab         matlab.ui.container.Tab
        LoadSystemsPanel                matlab.ui.container.Panel
        LoadFileButton                  matlab.ui.control.Button
        AvailableSolubilityDataSystemsLabel  matlab.ui.control.Label
        TableOfSolSys                   matlab.ui.control.Table
        ImportSelectedSystemButton      matlab.ui.control.Button
        DataFilePathLabel               matlab.ui.control.Label
        DataFilePathEditField           matlab.ui.control.EditField
        SelectSystemDropDownLabel       matlab.ui.control.Label
        SelectSystemDropDown            matlab.ui.control.DropDown
        CurrentlyDefinedExperimentalSolubilityDataPanel  matlab.ui.container.Panel
        ImportedSolDataTable            matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        SoluteEditFieldLabel            matlab.ui.control.Label
        SoluteEditField                 matlab.ui.control.EditField
        Solvent1EditFieldLabel          matlab.ui.control.Label
        Solvent1EditField               matlab.ui.control.EditField
        Solvent2EditFieldLabel          matlab.ui.control.Label
        Solvent2EditField               matlab.ui.control.EditField
        SystemTempCEditFieldLabel       matlab.ui.control.Label
        SystemTempCEditField            matlab.ui.control.NumericEditField
        SolventUnitEditFieldLabel       matlab.ui.control.Label
        SolventUnitEditField            matlab.ui.control.EditField
        SolubilityUnitEditFieldLabel    matlab.ui.control.Label
        SolubilityUnitEditField         matlab.ui.control.EditField
        RunModelTab                     matlab.ui.container.Tab
        ModelTabGroup                   matlab.ui.container.TabGroup
        JAREGTab                        matlab.ui.container.Tab
        RegularJouybanAcreeModelLabel   matlab.ui.control.Label
        RunJAREGCorrelationButton       matlab.ui.control.Button
        JAREG_CvalsT                    matlab.ui.control.Table
        JAVHFTab                        matlab.ui.container.Tab
        JouybanAcreeVantHoffModelLabel  matlab.ui.control.Label
        RunJAVHFCorrelationButton       matlab.ui.control.Button
        JAVHF_CvalsT                    matlab.ui.control.Table
        GSMTab                          matlab.ui.container.Tab
        GeneralSingleModelLabel         matlab.ui.control.Label
        RunGSMCorrelationButton         matlab.ui.control.Button
        GSM_CvalsT                      matlab.ui.control.Table
        NRTLTab                         matlab.ui.container.Tab
        NRTLModelLabel                  matlab.ui.control.Label
        NRTL_torT                       matlab.ui.control.Table
        HfusJmolLabel                   matlab.ui.control.Label
        HfusEditField                   matlab.ui.control.NumericEditField
        TfusKLabel                      matlab.ui.control.Label
        TfusEditField                   matlab.ui.control.NumericEditField
        RunNRTLParameterEstimationButton  matlab.ui.control.Button
        alpha_ijLabel                   matlab.ui.control.Label
        NRTL_alphaEditField             matlab.ui.control.NumericEditField
        UNIQUACTab                      matlab.ui.container.Tab
        UNIQ_torT                       matlab.ui.control.Table
        RunUNIQParameterEstimationButton  matlab.ui.control.Button
        HfuskJmolLabel_2                matlab.ui.control.Label
        HfusEditField_2                 matlab.ui.control.NumericEditField
        TfusKLabel_2                    matlab.ui.control.Label
        TfusEditField_2                 matlab.ui.control.NumericEditField
        UNQUACModelLabel                matlab.ui.control.Label
        LLSIGTab                        matlab.ui.container.Tab
        LogLinearYalkowskyModelLabel    matlab.ui.control.Label
        log10KowEditFieldLabel          matlab.ui.control.Label
        log10KowEditField               matlab.ui.control.NumericEditField
        SEditField_2Label               matlab.ui.control.Label
        SsigEditField                   matlab.ui.control.NumericEditField
        TEditField_2Label               matlab.ui.control.Label
        TsigEditField                   matlab.ui.control.NumericEditField
        DefPredsTable                   matlab.ui.control.Table
        AddExpSolDataButton             matlab.ui.control.Button
        ModelSetUpLabel                 matlab.ui.control.Label
        RunModelPanel                   matlab.ui.container.Panel
        DefSolPredictionsPanel          matlab.ui.container.Panel
        DefPredsButton                  matlab.ui.control.Button
        MinFracEditFieldLabel           matlab.ui.control.Label
        DefPred_min                     matlab.ui.control.NumericEditField
        MaxFracEditFieldLabel           matlab.ui.control.Label
        DefPred_max                     matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        DefPred_Step                    matlab.ui.control.NumericEditField
        ExpSolPredictionsPanel          matlab.ui.container.Panel
        ExpPredsButton                  matlab.ui.control.Button
        SelectModelDropDownLabel        matlab.ui.control.Label
        SelectModelDropDown             matlab.ui.control.DropDown
        ExpPredsTable                   matlab.ui.control.Table
        AddExpSolPredButton             matlab.ui.control.Button
        AddDefSolPredButton             matlab.ui.control.Button
        ClearGraphButton                matlab.ui.control.Button
        CopyExpPredsButton              matlab.ui.control.Button
        CopyDefPredsButton              matlab.ui.control.Button
        GraphControlsLabel              matlab.ui.control.Label
        ExperimentalPredictionsLabel    matlab.ui.control.Label
        DefinedPredictionsLabel         matlab.ui.control.Label
        TableControlsLabel              matlab.ui.control.Label
        ClearDefPredsButton             matlab.ui.control.Button
        ClearExpPredsButton             matlab.ui.control.Button
        ModelAxes                       matlab.ui.control.UIAxes
        PROGRAMSTATUSLampLabel          matlab.ui.control.Label
        PROGRAMSTATUSLamp               matlab.ui.control.Lamp
    end

    
    properties (Access = private)


        % Imported Exp. Solubility Data Global Variables
            exp_sol_DATASTORE; % datastore object for the Solubility Data spreadsheet
            identified_systems; % list of strings which act as keys for accessing exp_sol_DATASTORE (which they're determined from)
            
            % Currently defined solubility experimental data variables:
            sys_sol_data; %CURRENTLY IMPORTED SOLUBILITY DATA (table)
            sys_sol_unit; % Unit of solubility data (string)
            sys_frac_unit; %Units of solvent fraction (string)
            sys_T; %Temperature of Data (double)
            sys_solv1; % Name of primary solvent (string)
            sys_solv2; % Name of co-solvent (string)
            sys_solute; % Name of solute (string)
            
       % Imported Component Model Data Global Varibles
            comp_model_DATASTORE;
       
       % Imported Component Property Data Global Varibles
            comp_prop_DATASTORE;
            
      
        %Activity Co-Efficient Model Optimisation Properties
        %These get assigned and then cleared before/after optimisation
        %because they are also duplicate values of other variables.
            
            %NRTL:
            Rugc;  %R : Universal Gas constant (J/mol K) (double)
            Tfus; %Temperature of Fusion (K) (double)
            Hfus; %Enthalp of Fusion (kJ/mol) (double)
            Xexp; %Experimental solublity data (array)
            alpha; %Non-Random Interaction Paramater
            
            NRTLfun; %Function handle of NRTL_lng.m function
            
    end
    
    properties (Access = public)
        %Global variables of NRTLoptimisation function
             %global app.R app.NRTL_Tfus app.Tsys Hfus NRTLfun Xexp
    end
    
    methods (Access = private)
        
        function solt_frac = make_frac(app,min,max,step)
            % Preps solvent fraction array
            cosol = [min:step:max].';
            sol = 1-cosol;
            solt_frac = [sol,cosol];
        end


        function sol_preds = call_model(app,model_sel,sys_str)
            %Operates the preds_driver.m function based on model selection            
            switch  model_sel
                case 'JA REG'  
                    %Package System Data needed for JA REG predictions

                    sys_str.J0 = app.JAREG_CvalsT.Data(1,1); sys_str.J1 = app.JAREG_CvalsT.Data(1,2); sys_str.J2 = app.JAREG_CvalsT.Data(1,3); 
                    sys_str.Sc = app.sys_sol_data{end,3}; sys_str.Sp = app.sys_sol_data{1,3};
                    
                    %Call predictions function
                    sol_preds = pred_driver('JA_REG',sys_str);
                    
                case 'JA VHF'  
                    
                    %Package System Data needed for JA REG predictions
                    sys_str.T = app.sys_T + 273.15;
                    sys_str.J0 = app.JAVHF_CvalsT.Data(1,5); sys_str.J1 = app.JAVHF_CvalsT.Data(1,6); sys_str.J2 = app.JAVHF_CvalsT.Data(1,7); 
                    sys_str.A1 = app.JAVHF_CvalsT.Data(1,1); sys_str.B1 = app.JAVHF_CvalsT.Data(1,2); 
                    sys_str.A2 = app.JAVHF_CvalsT.Data(1,3); sys_str.B2 = app.JAVHF_CvalsT.Data(1,4);
                    
                    %Call predictions function
                    sol_preds = pred_driver('JA_VHF',sys_str);
                
                case 'GSM'
                    %Package System Data needed for GSM predictions
                    %GSM_pred_fun(fp,B0,B1,B2,B3,B4)
                    sys_str.B0 = app.GSM_CvalsT.Data(1,1); sys_str.B1 = app.GSM_CvalsT.Data(1,2); sys_str.B2 = app.GSM_CvalsT.Data(1,3); 
                    sys_str.B3 = app.GSM_CvalsT.Data(1,4); sys_str.B4 = app.GSM_CvalsT.Data(1,5); 
                    
                    %Call predictions function
                    sol_preds = pred_driver('GSM',sys_str);
                
                case 'NRTL'
                    sys_str.TOR = app.NRTL_torT.Data;
                    sys_str.alpha = app.alpha;
                    sys_str.Hfus = app.Hfus;
                    sys_str.Tfus = app.Tfus;
                    sys_str.Tsys = app.sys_T + 273.15;
                    
                    sol_preds = pred_driver('NRTL',sys_str);
                    %THIS NEEDS TO BE FINISHED
                    
                case 'UNIQUAC'
                    sys_str.TOR = app.UNIQ_torT.Data;
                    sys_str.Hfus = app.Hfus;
                    sys_str.Tfus = app.Tfus;
                    sys_str.Tsys = app.sys_T +273.15;
                    
                    sol_preds = pred_driver('UNIQUAC',sys_str);
                    
                case 'LL SIG'
                    %Package System Data needed for LL SIG predictions
                    sys_str.Sp = app.sys_sol_data{1,3}; 
                    sys_str.logKow = app.log10KowEditField.Value; sys_str.t = app.TsigEditField.Value; sys_str.s = app.SsigEditField.Value;
                        
                        
                    %Call predictions function
                    sol_preds = pred_driver('LL_SIG',sys_str);
                    
                case 'LL IMM'  
                    %Package System Data needed for LL IMM predictions
                    sys_str.Sc = app.sys_sol_data{end,3}; sys_str.Sp = app.sys_sol_data{1,3};
                    
                    %Call predictions function
                    sol_preds = pred_driver('LL_IMM',sys_str);
                    
                   
            end
            
        end
        
        function [F] = NRTL_objfun(app,tor)
             %Objective function for optimisation routine of NRTL paramater
            %estimation
            
            lng = zeros(length(app.Xexp),3);
            
            for p = 1:length(app.Xexp)
                
                lng(p,:) = app.NRTLfun([app.Xexp(p,1:2),0],app.alpha,tor);
            end
            
            %Calculate Predictated Points
            Xpred = exp(-(app.Hfus/app.Rugc)*(1/(app.sys_T+273.15) - 1/app.Tfus) - lng(:,3));  
            
            %Objective function of relative error
            F =   sum( ((app.Xexp(:,3)-Xpred(:))./app.Xexp(:,3)).^2); 
            
        end


        function [F] = UNIQ_objfun(app,tor)
            
            
            %Objective function for optimisation routie of UNIQUAC
            %parameter estimation
             lng = zeros(length(app.Xexp),3);
            
            for p = 1:length(app.Xexp)
                %!!! YOU NEED TO FIND A WAY TO IMPORT UNIQUAC PARAMETER
                lng(p,:) = app.UNIQfun(app.Rg, app.Qg, app.Cg, [app.Xexp(p,1:2),0],tor);
                
            end
            
            %Calculate Predictated Points
            Xpred = exp(-(app.Hfus/app.Rugc)*(1/(app.sys_T+273.15) - 1/app.Tfus) - lng(:,3));  
            
            %Objective function of relative error
            F =   sum( ((app.Xexp(:,3)-Xpred(:))./app.Xexp(:,3)).^2); 


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Adding paths to directory so that functions in these folders can be directly called.
            
            addpath("corr_funs\");
            addpath("pred_funs\");
            addpath("other_funs\");
            
            %Add NRTL function folders to path
            addpath("corr_funs\NRTL\")
            addpath("pred_funs\NRTL\")
            
            %Add UNIQUAC function folder to path
            addpath("pred_funs\UNIQUAC\")
            addpath("corr_funs\UNIQUAC\")
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
           
            %Select File to Import System Data as Data Store
            [sol_data_FILENAME,sol_data_FILEPATH] = uigetfile("../data_files");         
            app.DataFilePathEditField.Value = [sol_data_FILEPATH,sol_data_FILENAME];
            
            %Import Datastore
            app.exp_sol_DATASTORE = spreadsheetDatastore([sol_data_FILEPATH,sol_data_FILENAME]);
                          
            
            %Identify Systems within Sheets
            app.exp_sol_DATASTORE.SelectedVariableNames = {'solv_1','solv_2','solute'};
            
            app.identified_systems = rmmissing(read(app.exp_sol_DATASTORE)); %read systems in datastore
            app.identified_systems{:,end+1} = app.exp_sol_DATASTORE.sheetnames(1); %reads data and removes empty rows
            
            app.TableOfSolSys.Data = [app.identified_systems{:,1:4}];
                   
            app.SelectSystemDropDown.Items = app.TableOfSolSys.Data(:,4);
            
            %end


        end

        % Callback function
        function JAREGCorrelationButtonPushed(app, event)
            %!!! THIS NEEDS TO BE REMOVED
            app.return_Cvals =  JA_REG_corr_fun(app.sol_data_4_corr);
            app.ReturnCorr.Data = app.return_Cvals.';
        end

        % Button pushed function: ImportSelectedSystemButton
        function ImportSelectedSystemButtonPushed(app, event)
            %Prepare Datastore for Import
            app.exp_sol_DATASTORE.Sheets = app.SelectSystemDropDown.Value;
            app.exp_sol_DATASTORE.SelectedVariableNames = {'solv_frac1','solv_frac2','solt_sol'};
            
            %load solubility data itself
            app.sys_sol_data = app.exp_sol_DATASTORE.read();
            app.ImportedSolDataTable.Data = app.sys_sol_data;
            
            %load system data for selected data set
            reset(app.exp_sol_DATASTORE);
            app.exp_sol_DATASTORE.Sheets = app.SelectSystemDropDown.Value;
            app.exp_sol_DATASTORE.SelectedVariableNames = {'sys_T','solv_unit','sol_unit','solv_1','solv_2','solute'};
            temp_table = rmmissing(app.exp_sol_DATASTORE.read());
            
            
            app.sys_T =  temp_table{1,1};
            app.sys_frac_unit =  temp_table{1,2}{:};
            app.sys_sol_unit = temp_table{1,3}{:};
            app.sys_solv1 = temp_table{1,4}{:};
            app.sys_solv2 = temp_table{1,5}{:};
            app.sys_solute = temp_table{1,6}{:};
            
            app.SolubilityUnitEditField.Value = app.sys_sol_unit;
            app.SolventUnitEditField.Value = app.sys_frac_unit;
            app.Solvent1EditField.Value = app.sys_solv1;
            app.Solvent2EditField.Value = app.sys_solv2;
            app.SoluteEditField.Value =  app.sys_solute;
            app.SystemTempCEditField.Value = app.sys_T;
            
        end

        % Button pushed function: AddExpSolDataButton
        function AddExpSolDataButtonPushed(app, event)
            % Add imported solubility data to "Run Model" Axes
            % !!! This is just temporary for now, add more complete axes contols in the future
           
            if isempty(app.sys_sol_data) ~= 1
                plot(app.ModelAxes,app.sys_sol_data{:,'solv_frac2'},app.sys_sol_data{:,'solt_sol'},"xr","LineWidth",1.25);
            end
        end

        % Button pushed function: RunJAREGCorrelationButton
        function RunJAREGCorrelationButtonPushed(app, event)
            % Runs correlation on data with regular Jouyban Acree Model
            
            app.JAREG_CvalsT.Data = [JA_REG_corr_fun(app.sys_sol_data)].';
            
        end

        % Button pushed function: ExpPredsButton
        function ExpPredsButtonPushed(app, event)
            % Prep solvent fraction range based on imported exp. data
            sys_str.sol_data = app.sys_sol_data{:,1:2};
            % Call "call_model" to return solubilty predictions from preds_driver.m
            sol_preds = call_model(app,app.SelectModelDropDown.Value,sys_str);          
                
            %Update Table
            app.ExpPredsTable.Data = sol_preds;
        end

        % Button pushed function: DefPredsButton
        function DefPredsButtonPushed(app, event)
            % Prep solvent fraction range based on user input
            sys_str.sol_data = make_frac(app, app.DefPred_min.Value,app.DefPred_max.Value,app.DefPred_Step.Value);
            % Call "call_model" to return solubilty predictions from preds_driver.m
            sol_preds = call_model(app,app.SelectModelDropDown.Value,sys_str);
            
            %{
            switch  app.SelectModelDropDown.Value
                case 'JA REG'  
                    %Package System Data needed for JA REG predictions
                    sys_str.sol_data = make_frac(app, app.DefPred_min.Value,app.DefPred_max.Value,app.DefPred_Step.Value);
                    sys_str.J0 = app.JAREG_CvalsT.Data(1,1); sys_str.J1 = app.JAREG_CvalsT.Data(1,2); sys_str.J2 = app.JAREG_CvalsT.Data(1,3); 
                    sys_str.Sc = app.sys_sol_data{end,3}; sys_str.Sp = app.sys_sol_data{1,3};
                    
                    %Call predictions function
                    sol_preds = pred_driver('JA_REG',sys_str);
                    
                case 'LL IMM'  
                    %Package System Data needed for JA REG predictions
                    sys_str.sol_data = make_frac(app, app.DefPred_min.Value,app.DefPred_max.Value,app.DefPred_Step.Value);
                    sys_str.Sc = app.sys_sol_data{end,3}; sys_str.Sp = app.sys_sol_data{1,3};
                    
                    %Call predictions function
                    sol_preds = pred_driver('LL_IMM',sys_str);
            end
            %}
            %Update Table
            app.DefPredsTable.Data = sol_preds;
        end

        % Button pushed function: AddDefSolPredButton
        function AddDefSolPredButtonPushed(app, event)
            % Add defined solubility predictions to "Run Model" Axes
            % !!! This is just temporary for now, add more complete axes contols in the future
           
            if isempty(app.DefPredsTable.Data) ~= 1
                plot(app.ModelAxes,app.DefPredsTable.Data(:,2),app.DefPredsTable.Data(:,3),"-k");
            end
        end

        % Button pushed function: AddExpSolPredButton
        function AddExpSolPredButtonPushed(app, event)
            % Add defined solubility predictions to "Run Model" Axes
            % !!! This is just temporary for now, add more complete axes contols in the future
           
            if isempty(app.ExpPredsTable.Data) ~= 1
                plot(app.ModelAxes,app.ExpPredsTable.Data(:,2),app.ExpPredsTable.Data(:,3),"bo","LineWidth",1.25);
            end
        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            cla(app.ModelAxes);
        end

        % Button pushed function: CopyExpPredsButton
        function CopyExpPredsButtonPushed(app, event)
            arraystring = num2clip(app.ExpPredsTable.Data);
        end

        % Button pushed function: CopyDefPredsButton
        function CopyDefPredsButtonPushed(app, event)
            arraystring = num2clip(app.DefPredsTable.Data);
        end

        % Button pushed function: ClearDefPredsButton
        function ClearDefPredsButtonPushed(app, event)
            app.DefPredsTable.Data = [];
        end

        % Button pushed function: ClearExpPredsButton
        function ClearExpPredsButtonPushed(app, event)
            app.ExpPredsTable.Data = [];
        end

        % Callback function
        function RunJASIGCorrelationButtonPushed(app, event)
            % Correlate the Sigma Modified JA Model
            sigma = (app.SEditField.Value * app.logKowEditField.Value) + app.TEditField.Value;
            app.JASIG_CvalsT.Data = [JA_SIG_corr_fun(app.sys_sol_data,sigma)].'; 
        end

        % Button pushed function: RunJAVHFCorrelationButton
        function RunJAVHFCorrelationButtonPushed(app, event)
            app.JAVHF_CvalsT.Data = [JA_VHF_corr_fun(app.sys_sol_data,app.sys_T+273.15)].'; 
        end

        % Button pushed function: RunGSMCorrelationButton
        function RunGSMCorrelationButtonPushed(app, event)
           app.GSM_CvalsT.Data = [GSM_corr_fun(app.sys_sol_data)].';  
        end

        % Button pushed function: RunNRTLParameterEstimationButton
        function RunNRTLParameterEstimationButtonPushed(app, event)
            app.NRTL_torT.Data = zeros(3);
            
            %Assign global paranerers for objective function.
            app.NRTLfun = @NRTL_lng; %assign NRTL function from NRTL_lng.m
            app.Rugc = 8.314;  %R : Universal Gas constant (J/mol K) (double)
            app.Tfus = app.TfusEditField.Value ; %Temperature of Fusion (K) (double)
            app.Hfus = app.HfusEditField.Value; %Enthalp of Fusion (kJ/mol) (double)
            app.Xexp = app.sys_sol_data{:,:}; %Experimental solublity data (array)
            app.alpha = app.NRTL_alphaEditField.Value; %Non-Random Interaction Paramater
            
            %Assign optimisation definitions
            funNRTL_NLcon = @NRTL_NLcon; %Non-linear eq. & ineq. cons
            
            A = []; %Linear ineq. cons
            b = [];
            Aeq = []; %Linear eq. const
            beq = [];
            lb = []; %upper bound
            ub = []; %lower bound
            
            x0 = zeros(3);
            
            TOR = fmincon(@app.NRTL_objfun,x0,A,b,Aeq,beq,lb,ub,@NRTL_NLcon);
            app.NRTL_torT.Data = TOR;
            
            %}
        end

        % Button pushed function: RunUNIQParameterEstimationButton
        function RunUNIQParameterEstimationButtonPushed(app, event)
            app.UNIQ_torT.Data = zeros(3);
            
            %Assign global paranerers for objective function.
            app.UNIQfun = @UNIQUAC_lng;
            app.Rugc = 8.314;  %R : Universal Gas constant (J/mol K) (double)
            app.Tfus = app.TfusEditField_2.Value ; %Temperature of Fusion (K) (double)
            app.Hfus = app.HfusEditField_2.Value; %Enthalp of Fusion (kJ/mol) (double)
            app.Xexp = app.sys_sol_data{:,:}; %Experimental solublity data (array)
            
            
            %Assign optimisation definitions
            % @UNIQUAC_NLcon; %Non-linear eq. & ineq. cons
            
            A = []; %Linear ineq. cons
            b = [];
            Aeq = []; %Linear eq. const
            beq = [];
            lb = []; %upper bound
            ub = []; %lower bound
            
            x0 = ones(3);
            
            TOR = fmincon(@app.UNIQ_objfun,x0,A,b,Aeq,beq,lb,ub,@UNIQUAC_NLcon);
            
            app.UNIQ_torT.Data = TOR;
            
            
            %}           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupImportTab = uitab(app.TabGroup);
            app.SetupImportTab.Title = 'Setup: Import';
            app.TabGroup3 = uitabgroup(app.SetupImportTab);
            app.ImportSystemfromFileTab = uitab(app.TabGroup3);
            app.ImportSystemfromFileTab.Title = 'Import System from File';
            app.LoadSystemsPanel = uipanel(app.ImportSystemfromFileTab);
            app.LoadSystemsPanel.Title = 'Load Systems';
            app.LoadFileButton = uibutton(app.LoadSystemsPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.AvailableSolubilityDataSystemsLabel = uilabel(app.LoadSystemsPanel);
            app.AvailableSolubilityDataSystemsLabel.Text = 'Available Solubility Data Systems';
            app.TableOfSolSys = uitable(app.LoadSystemsPanel);
            app.TableOfSolSys.ColumnName = {'Solvent 1'; 'Solvent 2'; 'Solute'; 'System Key'};
            app.TableOfSolSys.RowName = {};
            app.TableOfSolSys.ColumnEditable = true;
            app.ImportSelectedSystemButton = uibutton(app.LoadSystemsPanel, 'push');
            app.ImportSelectedSystemButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSelectedSystemButtonPushed, true);
            app.ImportSelectedSystemButton.Text = 'Import Selected System';
            app.DataFilePathLabel = uilabel(app.LoadSystemsPanel);
            app.DataFilePathLabel.Text = 'Data File Path:';
            app.DataFilePathEditField = uieditfield(app.LoadSystemsPanel, 'text');
            app.SelectSystemDropDownLabel = uilabel(app.LoadSystemsPanel);
            app.SelectSystemDropDownLabel.Text = 'Select System';
            app.SelectSystemDropDown = uidropdown(app.LoadSystemsPanel);
            app.CurrentlyDefinedExperimentalSolubilityDataPanel = uipanel(app.ImportSystemfromFileTab);
            app.CurrentlyDefinedExperimentalSolubilityDataPanel.Title = 'Currently Defined Experimental Solubility Data';
            app.ImportedSolDataTable = uitable(app.CurrentlyDefinedExperimentalSolubilityDataPanel);
            app.ImportedSolDataTable.ColumnName = {'Solvent 1 Fraction'; 'Solvent 2 Fraction'; 'Solubility'};
            app.ImportedSolDataTable.RowName = {};
            app.Panel = uipanel(app.CurrentlyDefinedExperimentalSolubilityDataPanel);
            app.SoluteEditFieldLabel = uilabel(app.Panel);
            app.SoluteEditFieldLabel.Text = 'Solute';
            app.SoluteEditField = uieditfield(app.Panel, 'text');
            app.Solvent1EditFieldLabel = uilabel(app.Panel);
            app.Solvent1EditFieldLabel.Text = 'Solvent 1';
            app.Solvent1EditField = uieditfield(app.Panel, 'text');
            app.Solvent2EditFieldLabel = uilabel(app.Panel);
            app.Solvent2EditFieldLabel.Text = 'Solvent 2';
            app.Solvent2EditField = uieditfield(app.Panel, 'text');
            app.SystemTempCEditFieldLabel = uilabel(app.Panel);
            app.SystemTempCEditFieldLabel.Text = 'System Temp. (C)';
            app.SystemTempCEditField = uieditfield(app.Panel, 'numeric');
            app.SolventUnitEditFieldLabel = uilabel(app.Panel);
            app.SolventUnitEditFieldLabel.Text = 'Solvent Unit';
            app.SolventUnitEditField = uieditfield(app.Panel, 'text');
            app.SolubilityUnitEditFieldLabel = uilabel(app.Panel);
            app.SolubilityUnitEditFieldLabel.Text = 'Solubility Unit';
            app.SolubilityUnitEditField = uieditfield(app.Panel, 'text');
            app.RunModelTab = uitab(app.TabGroup);
            app.RunModelTab.Title = 'Run Model';
            app.ModelTabGroup = uitabgroup(app.RunModelTab);
            app.JAREGTab = uitab(app.ModelTabGroup);
            app.JAREGTab.Title = 'JA REG';
            app.RegularJouybanAcreeModelLabel = uilabel(app.JAREGTab);
            app.RegularJouybanAcreeModelLabel.Text = 'Regular Jouyban-Acree Model';
            app.RunJAREGCorrelationButton = uibutton(app.JAREGTab, 'push');
            app.RunJAREGCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @RunJAREGCorrelationButtonPushed, true);
            app.RunJAREGCorrelationButton.Text = {'Run JA REG'; 'Correlation'};
            app.JAREG_CvalsT = uitable(app.JAREGTab);
            app.JAREG_CvalsT.ColumnName = {'J0'; 'J1'; 'J2'};
            app.JAREG_CvalsT.RowName = {};
            app.JAVHFTab = uitab(app.ModelTabGroup);
            app.JAVHFTab.Title = 'JA VHF';
            app.JouybanAcreeVantHoffModelLabel = uilabel(app.JAVHFTab);
            app.JouybanAcreeVantHoffModelLabel.Text = 'Jouyban-Acree V''ant Hoff Model';
            app.RunJAVHFCorrelationButton = uibutton(app.JAVHFTab, 'push');
            app.RunJAVHFCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @RunJAVHFCorrelationButtonPushed, true);
            app.RunJAVHFCorrelationButton.Text = {'Run JA REG'; 'Correlation'};
            app.JAVHF_CvalsT = uitable(app.JAVHFTab);
            app.JAVHF_CvalsT.ColumnName = {'A1'; 'B1'; 'A2'; 'B2'; 'J0'; 'J1'; 'J2'};
            app.JAVHF_CvalsT.RowName = {};
            app.GSMTab = uitab(app.ModelTabGroup);
            app.GSMTab.Title = 'GSM';
            app.GeneralSingleModelLabel = uilabel(app.GSMTab);
            app.GeneralSingleModelLabel.Text = 'General Single Model';
            app.RunGSMCorrelationButton = uibutton(app.GSMTab, 'push');
            app.RunGSMCorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @RunGSMCorrelationButtonPushed, true);
            app.RunGSMCorrelationButton.Text = {'Run GSM'; 'Correlation'};
            app.GSM_CvalsT = uitable(app.GSMTab);
            app.GSM_CvalsT.ColumnName = {'B0'; 'B1'; 'B2'; 'B3'; 'B4'};
            app.GSM_CvalsT.RowName = {};
            app.NRTLTab = uitab(app.ModelTabGroup);
            app.NRTLTab.Title = 'NRTL';
            app.NRTLModelLabel = uilabel(app.NRTLTab);
            app.NRTLModelLabel.Text = 'NRTL Model';
            app.NRTL_torT = uitable(app.NRTLTab);
            app.NRTL_torT.ColumnName = {'j = 1'; 'j = 2'; 'j = 3 '};
            app.NRTL_torT.RowName = {'i =1'; 'i = 2'; 'i = 3'};
            app.HfusJmolLabel = uilabel(app.NRTLTab);
            app.HfusJmolLabel.Text = 'ΔHfus (J/mol)';
            app.HfusEditField = uieditfield(app.NRTLTab, 'numeric');
            app.HfusEditField.ValueDisplayFormat = '%11.6g';
            app.HfusEditField.Value = 91884;
            app.TfusKLabel = uilabel(app.NRTLTab);
            app.TfusKLabel.Text = 'Tfus (K)';
            app.TfusEditField = uieditfield(app.NRTLTab, 'numeric');
            app.TfusEditField.Value = 553.15;
            app.RunNRTLParameterEstimationButton = uibutton(app.NRTLTab, 'push');
            app.RunNRTLParameterEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @RunNRTLParameterEstimationButtonPushed, true);
            app.RunNRTLParameterEstimationButton.Text = {'Run NRTL'; 'Parameter Estimation'};
            app.alpha_ijLabel = uilabel(app.NRTLTab);
            app.alpha_ijLabel.Text = 'alpha_ij';
            app.NRTL_alphaEditField = uieditfield(app.NRTLTab, 'numeric');
            app.NRTL_alphaEditField.Value = 0.5;
            app.UNIQUACTab = uitab(app.ModelTabGroup);
            app.UNIQUACTab.Title = 'UNIQUAC';
            app.UNIQ_torT = uitable(app.UNIQUACTab);
            app.UNIQ_torT.ColumnName = {'j = 1'; 'j = 2'; 'j = 3 '};
            app.UNIQ_torT.RowName = {'i =1'; 'i = 2'; 'i = 3'};
            app.RunUNIQParameterEstimationButton = uibutton(app.UNIQUACTab, 'push');
            app.RunUNIQParameterEstimationButton.ButtonPushedFcn = createCallbackFcn(app, @RunUNIQParameterEstimationButtonPushed, true);
            app.RunUNIQParameterEstimationButton.Text = {'Run UNIQUAC'; 'Parameter Estimation'};
            app.HfuskJmolLabel_2 = uilabel(app.UNIQUACTab);
            app.HfuskJmolLabel_2.Text = 'ΔHfus (kJ/mol)';
            app.HfusEditField_2 = uieditfield(app.UNIQUACTab, 'numeric');
            app.HfusEditField_2.Value = 91.8;
            app.TfusKLabel_2 = uilabel(app.UNIQUACTab);
            app.TfusKLabel_2.Text = 'Tfus (K)';
            app.TfusEditField_2 = uieditfield(app.UNIQUACTab, 'numeric');
            app.TfusEditField_2.Value = 553.15;
            app.UNQUACModelLabel = uilabel(app.UNIQUACTab);
            app.UNQUACModelLabel.Text = 'UNQUAC Model';
            app.LLSIGTab = uitab(app.ModelTabGroup);
            app.LLSIGTab.Title = 'LL SIG';
            app.LogLinearYalkowskyModelLabel = uilabel(app.LLSIGTab);
            app.LogLinearYalkowskyModelLabel.Text = 'Log-Linear Yalkowsky Model';
            app.log10KowEditFieldLabel = uilabel(app.LLSIGTab);
            app.log10KowEditFieldLabel.Text = 'log10Kow';
            app.log10KowEditField = uieditfield(app.LLSIGTab, 'numeric');
            app.SEditField_2Label = uilabel(app.LLSIGTab);
            app.SEditField_2Label.Text = 'S';
            app.SsigEditField = uieditfield(app.LLSIGTab, 'numeric');
            app.TEditField_2Label = uilabel(app.LLSIGTab);
            app.TEditField_2Label.Text = 'T';
            app.TsigEditField = uieditfield(app.LLSIGTab, 'numeric');
            app.DefPredsTable = uitable(app.RunModelTab);
            app.DefPredsTable.ColumnName = {'Solvent 1 Fraction'; 'Solvent 2 Fraction'; 'Solubility'};
            app.DefPredsTable.RowName = {};
            app.AddExpSolDataButton = uibutton(app.RunModelTab, 'push');
            app.AddExpSolDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddExpSolDataButtonPushed, true);
            app.AddExpSolDataButton.Text = {'Add Exp. '; 'Sol. Data'};
            app.ModelSetUpLabel = uilabel(app.RunModelTab);
            app.ModelSetUpLabel.Text = 'Model Set-Up';
            app.RunModelPanel = uipanel(app.RunModelTab);
            app.RunModelPanel.Title = 'Run Model';
            app.DefSolPredictionsPanel = uipanel(app.RunModelPanel);
            app.DefSolPredictionsPanel.Title = 'Def. Sol. Predictions';
            app.DefPredsButton = uibutton(app.DefSolPredictionsPanel, 'push');
            app.DefPredsButton.ButtonPushedFcn = createCallbackFcn(app, @DefPredsButtonPushed, true);
            app.DefPredsButton.Text = {'Perform Defined'; 'Predictions'};
            app.MinFracEditFieldLabel = uilabel(app.DefSolPredictionsPanel);
            app.MinFracEditFieldLabel.Text = 'Min Frac.';
            app.DefPred_min = uieditfield(app.DefSolPredictionsPanel, 'numeric');
            app.DefPred_min.Limits = [0 1];
            app.MaxFracEditFieldLabel = uilabel(app.DefSolPredictionsPanel);
            app.MaxFracEditFieldLabel.Text = 'Max Frac.';
            app.DefPred_max = uieditfield(app.DefSolPredictionsPanel, 'numeric');
            app.DefPred_max.Limits = [0 1];
            app.DefPred_max.Value = 1;
            app.StepSizeEditFieldLabel = uilabel(app.DefSolPredictionsPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.DefPred_Step = uieditfield(app.DefSolPredictionsPanel, 'numeric');
            app.DefPred_Step.Limits = [0 1];
            app.DefPred_Step.Value = 0.05;
            app.ExpSolPredictionsPanel = uipanel(app.RunModelPanel);
            app.ExpSolPredictionsPanel.Title = 'Exp. Sol. Predictions';
            app.ExpPredsButton = uibutton(app.ExpSolPredictionsPanel, 'push');
            app.ExpPredsButton.ButtonPushedFcn = createCallbackFcn(app, @ExpPredsButtonPushed, true);
            app.ExpPredsButton.Text = {'Perform Exp.'; 'Predictions'};
            app.SelectModelDropDownLabel = uilabel(app.RunModelPanel);
            app.SelectModelDropDownLabel.Text = 'Select Model';
            app.SelectModelDropDown = uidropdown(app.RunModelPanel);
            app.SelectModelDropDown.Items = {'JA REG', 'JA VHF', 'GSM', 'LL IMM', 'LL SIG', 'NRTL', ''};
            app.SelectModelDropDown.Value = 'JA REG';
            app.ExpPredsTable = uitable(app.RunModelTab);
            app.ExpPredsTable.ColumnName = {'Solvent 1 Fraction'; 'Solvent 2 Fraction'; 'Solubility'};
            app.ExpPredsTable.RowName = {};
            app.AddExpSolPredButton = uibutton(app.RunModelTab, 'push');
            app.AddExpSolPredButton.ButtonPushedFcn = createCallbackFcn(app, @AddExpSolPredButtonPushed, true);
            app.AddExpSolPredButton.Text = {'Add Exp. '; 'Sol. Pred'};
            app.AddDefSolPredButton = uibutton(app.RunModelTab, 'push');
            app.AddDefSolPredButton.ButtonPushedFcn = createCallbackFcn(app, @AddDefSolPredButtonPushed, true);
            app.AddDefSolPredButton.Text = {'Add Def '; 'Sol. Pred'};
            app.ClearGraphButton = uibutton(app.RunModelTab, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = {'Clear'; 'Graph'};
            app.CopyExpPredsButton = uibutton(app.RunModelTab, 'push');
            app.CopyExpPredsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyExpPredsButtonPushed, true);
            app.CopyExpPredsButton.Text = {'Copy Exp.'; 'Preds'};
            app.CopyDefPredsButton = uibutton(app.RunModelTab, 'push');
            app.CopyDefPredsButton.ButtonPushedFcn = createCallbackFcn(app, @CopyDefPredsButtonPushed, true);
            app.CopyDefPredsButton.Text = {'Copy Def.'; 'Preds'};
            app.GraphControlsLabel = uilabel(app.RunModelTab);
            app.GraphControlsLabel.Text = 'Graph Controls';
            app.ExperimentalPredictionsLabel = uilabel(app.RunModelTab);
            app.ExperimentalPredictionsLabel.Text = 'Experimental Predictions';
            app.DefinedPredictionsLabel = uilabel(app.RunModelTab);
            app.DefinedPredictionsLabel.Text = 'Defined Predictions';
            app.TableControlsLabel = uilabel(app.RunModelTab);
            app.TableControlsLabel.Text = 'Table Controls';
            app.ClearDefPredsButton = uibutton(app.RunModelTab, 'push');
            app.ClearDefPredsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDefPredsButtonPushed, true);
            app.ClearDefPredsButton.Text = {'Clear Def.'; 'Preds'};
            app.ClearExpPredsButton = uibutton(app.RunModelTab, 'push');
            app.ClearExpPredsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearExpPredsButtonPushed, true);
            app.ClearExpPredsButton.Text = {'Clear Exp.'; 'Preds'};
            app.ModelAxes = uiaxes(app.RunModelTab);
            title(app.ModelAxes, 'Title')
            xlabel(app.ModelAxes, 'X')
            ylabel(app.ModelAxes, 'Y')
            zlabel(app.ModelAxes, 'Z')
            app.PROGRAMSTATUSLampLabel = uilabel(app.UIFigure);
            app.PROGRAMSTATUSLampLabel.Text = 'PROGRAM STATUS';
            app.PROGRAMSTATUSLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolModelTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end